classdef BladeIn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddingAirfoilUIFigure  matlab.ui.Figure
        PositionLabel          matlab.ui.control.Label
        dr                     matlab.ui.control.NumericEditField
        Hinzu                  matlab.ui.control.Button
        mLabel                 matlab.ui.control.Label
        Entfern                matlab.ui.control.Button
        AirfoilDropDownLabel   matlab.ui.control.Label
        Profil                 matlab.ui.control.DropDown
        Label                  matlab.ui.control.Label
        profilaxisLabel        matlab.ui.control.Label
        t                      matlab.ui.control.NumericEditField
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp 
        foil_db=readtable("foils_imported.dat")
    end
    
    properties (Access = public)
    
    end
    
    methods (Access = private)
        function plot_foil(app,name)
            filepath=join(['imported\',name,'_coords.dat'])
            A=readmatrix(filepath);
            plot(app.UIAxes,A(:,1),A(:,2))
            axis (app.UIAxes, 'equal' )
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;


            app.Profil.Items= string(app.foil_db.foil_name)
            app.Label.Text=app.Profil.Value
            plot_foil(app,app.Profil.Value)
 %           app.Profil.Value= string(app.foil_db(1).foil_name)
        end

        % Button pushed function: Entfern
        function EntfernButtonPushed(app, event)
%           app.CallingApp.OptionsButton.Enable = 'on';
            app.CallingApp.plus.Enable = 'on';
            delete(app)
        end

        % Button pushed function: Hinzu
        function HinzuButtonPushed(app, event)
                add_blade(app.CallingApp, app.Profil.Value,app.dr.Value,app.t.Value)   
        end

        % Value changed function: Profil
        function ProfilValueChanged(app, event)
            value = app.Profil.Value;
            app.Label.Text=app.Profil.Value
            plot_foil(app,app.Profil.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddingAirfoilUIFigure = uifigure('Visible', 'off');
            app.AddingAirfoilUIFigure.Name = 'Adding Airfoil';
            app.PositionLabel = uilabel(app.AddingAirfoilUIFigure);
            app.PositionLabel.Text = 'at the Radius of';
            app.dr = uieditfield(app.AddingAirfoilUIFigure, 'numeric');
            app.dr.Limits = [0 Inf];
            app.dr.ValueDisplayFormat = '%.2f';
            app.dr.Value = 15;
            app.Hinzu = uibutton(app.AddingAirfoilUIFigure, 'push');
            app.Hinzu.ButtonPushedFcn = createCallbackFcn(app, @HinzuButtonPushed, true);
            app.Hinzu.Text = 'Adding';
            app.mLabel = uilabel(app.AddingAirfoilUIFigure);
            app.mLabel.Text = '[  m  ]';
            app.Entfern = uibutton(app.AddingAirfoilUIFigure, 'push');
            app.Entfern.ButtonPushedFcn = createCallbackFcn(app, @EntfernButtonPushed, true);
            app.Entfern.Text = 'Back';
            app.AirfoilDropDownLabel = uilabel(app.AddingAirfoilUIFigure);
            app.AirfoilDropDownLabel.Text = 'Airfoil';
            app.Profil = uidropdown(app.AddingAirfoilUIFigure);
            app.Profil.Items = {'NACA0012', 'NACA0014'};
            app.Profil.ValueChangedFcn = createCallbackFcn(app, @ProfilValueChanged, true);
            app.Profil.Value = 'NACA0012';
            app.Label = uilabel(app.AddingAirfoilUIFigure);
            app.Label.Text = {'Label'; ''};
            app.profilaxisLabel = uilabel(app.AddingAirfoilUIFigure);
            app.profilaxisLabel.Text = 'profil axis';
            app.t = uieditfield(app.AddingAirfoilUIFigure, 'numeric');
            app.t.Limits = [0 Inf];
            app.t.ValueDisplayFormat = '%.2f';
            app.t.Value = 0.25;
            app.UIAxes = uiaxes(app.AddingAirfoilUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.AddingAirfoilUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BladeIn(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddingAirfoilUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddingAirfoilUIFigure)
        end
    end
end